classdef verifica_nodi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VERIFICANODIECALCOLOARMATURELabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MaterialiesollecitazioniTab     matlab.ui.container.Tab
        RealizzatodaGianPioScottodiMaseLabel_5  matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        SollecitazioniPanel             matlab.ui.container.Panel
        Image3                          matlab.ui.control.Image
        VckNEditField                   matlab.ui.control.NumericEditField
        VckNLabel                       matlab.ui.control.Label
        N_maxkNEditField                matlab.ui.control.NumericEditField
        NmaxkNLabel                     matlab.ui.control.Label
        N_minkNEditField                matlab.ui.control.NumericEditField
        N_minkNEditFieldLabel           matlab.ui.control.Label
        MaterialiPanel                  matlab.ui.container.Panel
        f_ydNmm2EditField               matlab.ui.control.NumericEditField
        f_ydNmm2EditFieldLabel          matlab.ui.control.Label
        f_ckNmm2EditField               matlab.ui.control.NumericEditField
        f_ckNmm2EditFieldLabel          matlab.ui.control.Label
        f_ctdNmm2EditField              matlab.ui.control.NumericEditField
        f_ctdNmm2EditFieldLabel         matlab.ui.control.Label
        f_ctkNmm2EditField              matlab.ui.control.NumericEditField
        f_ctkNmm2EditFieldLabel         matlab.ui.control.Label
        f_ctmNmm2EditField              matlab.ui.control.NumericEditField
        f_ctmNmm2EditFieldLabel         matlab.ui.control.Label
        f_cdNmm2EditField               matlab.ui.control.NumericEditField
        f_cdNmm2EditFieldLabel          matlab.ui.control.Label
        f_ykNmm2EditField               matlab.ui.control.NumericEditField
        f_ykNmm2Label                   matlab.ui.control.Label
        AcciaioDropDown                 matlab.ui.control.DropDown
        AcciaioDropDownLabel            matlab.ui.control.Label
        CalcestruzzoDropDown            matlab.ui.control.DropDown
        CalcestruzzoDropDownLabel       matlab.ui.control.Label
        PosizioneTab                    matlab.ui.container.Tab
        TraviconvergentiButtonGroup     matlab.ui.container.ButtonGroup
        Pianoyz2Pianoxz1Label           matlab.ui.control.Label
        EsempioLabel                    matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        Label_11                        matlab.ui.control.Label
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        RealizzatodaGianPioScottodiMaseLabel_3  matlab.ui.control.Label
        EsempidinodiPanel               matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        ClassediduttilitDropDown        matlab.ui.control.DropDown
        ClassediduttilitDropDownLabel   matlab.ui.control.Label
        NodoDropDown                    matlab.ui.control.DropDown
        NodoDropDownLabel               matlab.ui.control.Label
        DefinirelaposizionedelnodoelaclassediduttilitdelledificioLabel  matlab.ui.control.Label
        GeometriaearmatureTab           matlab.ui.container.Tab
        TravefrontalePanel              matlab.ui.container.Panel
        copriferro_fmmEditField         matlab.ui.control.NumericEditField
        copriferro_fmmEditFieldLabel    matlab.ui.control.Label
        A_fs2mm2EditField               matlab.ui.control.NumericEditField
        A_fs2mm2EditFieldLabel          matlab.ui.control.Label
        A_fs1mm2EditField               matlab.ui.control.NumericEditField
        A_fs1mm2EditFieldLabel          matlab.ui.control.Label
        h_tfmmEditField                 matlab.ui.control.NumericEditField
        h_tfmmLabel                     matlab.ui.control.Label
        b_tfmmEditField                 matlab.ui.control.NumericEditField
        b_tfmmEditFieldLabel            matlab.ui.control.Label
        PilastroinferiorePanel          matlab.ui.container.Panel
        copriferro_infmmEditField       matlab.ui.control.NumericEditField
        copriferro_infmmEditFieldLabel  matlab.ui.control.Label
        h_cinfmmEditField               matlab.ui.control.NumericEditField
        h_cinfmmEditFieldLabel          matlab.ui.control.Label
        b_cinfmmEditField               matlab.ui.control.NumericEditField
        b_cinfmmEditFieldLabel          matlab.ui.control.Label
        DimensionipannelloPanel         matlab.ui.container.Panel
        CalcolaButton_2                 matlab.ui.control.Button
        Image6                          matlab.ui.control.Image
        hjwmmEditField                  matlab.ui.control.NumericEditField
        hwmmLabel                       matlab.ui.control.Label
        hjcmmEditField                  matlab.ui.control.NumericEditField
        hcmmLabel                       matlab.ui.control.Label
        bjmmEditField                   matlab.ui.control.NumericEditField
        bjmmLabel                       matlab.ui.control.Label
        Image7                          matlab.ui.control.Image
        RealizzatodaGianPioScottodiMaseLabel_2  matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        PilastrosuperiorePanel          matlab.ui.container.Panel
        copriferro_supmmEditField       matlab.ui.control.NumericEditField
        copriferro_supmmEditFieldLabel  matlab.ui.control.Label
        h_csupmmEditField               matlab.ui.control.NumericEditField
        h_csupmmEditFieldLabel          matlab.ui.control.Label
        b_csupmmEditField               matlab.ui.control.NumericEditField
        b_csupmmEditFieldLabel          matlab.ui.control.Label
        TravedestraPanel                matlab.ui.container.Panel
        copriferro_dxmmEditField        matlab.ui.control.NumericEditField
        copriferro_dxmmEditFieldLabel   matlab.ui.control.Label
        A_dxs2mm2EditField              matlab.ui.control.NumericEditField
        A_dxs2mm2EditFieldLabel         matlab.ui.control.Label
        A_dxs1mm2EditField              matlab.ui.control.NumericEditField
        A_dxs1mm2EditFieldLabel         matlab.ui.control.Label
        h_tdxmmEditField                matlab.ui.control.NumericEditField
        h_tdxmmEditFieldLabel           matlab.ui.control.Label
        b_tdxmmEditField                matlab.ui.control.NumericEditField
        b_tdxmmEditFieldLabel           matlab.ui.control.Label
        TravesinistraPanel              matlab.ui.container.Panel
        copriferro_sxmmEditField        matlab.ui.control.NumericEditField
        copriferro_sxmmEditFieldLabel   matlab.ui.control.Label
        A_sxs2mm2EditField              matlab.ui.control.NumericEditField
        As2mm2Label                     matlab.ui.control.Label
        A_sxs1mm2EditField              matlab.ui.control.NumericEditField
        As1mm2Label                     matlab.ui.control.Label
        h_tsxmmEditField                matlab.ui.control.NumericEditField
        h_tsxmmEditFieldLabel           matlab.ui.control.Label
        b_tsxmmEditField                matlab.ui.control.NumericEditField
        b_tsxmmEditFieldLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        VerificaTab                     matlab.ui.container.Tab
        RealizzatodaGianPioScottodiMaseLabel_4  matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        VerificaatrazioneconarmatureLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        fracA_shcdotf_ywdbjcdoth_jwgeqfracf_ctdnu_dcdotf_cdf_ctdLabel  matlab.ui.control.Label
        VerificaatrazionePanel          matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        ResistenteLabel_2               matlab.ui.control.Label
        sigma_jtfracNmm2Label           matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        kNEditField_2                   matlab.ui.control.NumericEditField
        ResistenzaacompressionekNEditField_2  matlab.ui.control.NumericEditField
        CalcolaButton                   matlab.ui.control.Button
        CalcoloarmaturaPanel            matlab.ui.container.Panel
        Ashmm2EditField_3               matlab.ui.control.NumericEditField
        Aswmm2Label_3                   matlab.ui.control.Label
        ArmaturaminimadagarantireLabel  matlab.ui.control.Label
        Postfessurazione2Label          matlab.ui.control.Label
        Prefessurazione1Label           matlab.ui.control.Label
        Ashmm2EditField_2               matlab.ui.control.NumericEditField
        Aswmm2Label_2                   matlab.ui.control.Label
        Ashmm2EditField                 matlab.ui.control.NumericEditField
        Aswmm2Label                     matlab.ui.control.Label
        VerificaacompressionePanel      matlab.ui.container.Panel
        ResistenteLabel                 matlab.ui.control.Label
        VjbdkNLabel                     matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        kNEditField                     matlab.ui.control.NumericEditField
        ResistenzaacompressionekNEditField  matlab.ui.control.NumericEditField
        ContextMenu                     matlab.ui.container.ContextMenu
        Nodointerno2traviconvergentiMenu  matlab.ui.container.Menu
        Nodoesterno1traveconvergenteMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        fyk % Description
        fck
        fcd
        fyd
        fctm
        fctk
        fctd


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fyk = app.f_ykNmm2EditField.Value 

            fck = 0.83*30
            app.fcd = 0.85*fck/1.5
            app.fyd = app.fyk/1.15
            app.fctm = 0.3*fck^(2/3)
            app.fctk = 0.7*app.fctm
            app.fctd = app.fctk/1.5
            app.fck = fck

        end

        % Button pushed function: CalcolaButton
        function CalcolaButtonPushed(app, event)
            if app.Button_2.Value 
            
            bjc = app.bjmmEditField.Value
            hjc = app.hjcmmEditField.Value
            hjw = app.hjwmmEditField.Value
            Nmax = app.N_maxkNEditField.Value*1000
            Nmin = app.N_minkNEditField.Value*1000

            
            bcinf = app.b_cinfmmEditField.Value
            bcsup = app.b_csupmmEditField.Value
            btdx = app.b_tdxmmEditField.Value
            btsx = app.b_tsxmmEditField.Value
            hcinf = app.h_cinfmmEditField.Value
            hcsup = app.h_csupmmEditField.Value
            cinf = app.copriferro_infmmEditField.Value
            csup = app.copriferro_supmmEditField.Value
            cdx = app.copriferro_dxmmEditField.Value
            csx = app.copriferro_sxmmEditField.Value
            htdx = app.h_tdxmmEditField.Value
            htsx = app.h_tsxmmEditField.Value
            As1dx = app.A_dxs1mm2EditField.Value
            As1sx = app.A_sxs1mm2EditField.Value  
            As2dx = app.A_dxs2mm2EditField.Value
            As2sx = app.A_sxs2mm2EditField.Value


            bjc = min(min(bcinf,bcsup),min(btsx,btdx))
            app.bjmmEditField.Value = bjc
            hjc = min(hcinf-2*cinf, hcsup-2*csup)
            app.hjcmmEditField.Value = hjc
            hjw = min(htdx-2*cdx,htsx-2*csx)
            app.hjwmmEditField.Value = hjw


            classe = app.CalcestruzzoDropDown.Value
            if classe == 'C28/35'
                fck = 0.83*35
            elseif classe == 'C20/25'
                fck = 0.83*25
            elseif classe == 'C25/30'
                fck = 0.83*30
            elseif classe == 'C32/40'
                fck = 0.83*40
            elseif classe == 'C35/45'
                fck = 0.83*45
            elseif classe == 'C40/50'
                fck = 0.83*50
            elseif classe == 'C45/55'
                fck = 0.83*55
            elseif classe == 'C16/20'
                fck = 0.83*20
            else
                fck = app.f_ckNmm2EditField.Value


            end
  
            fcd = 0.85*fck/1.5
            fyd = app.f_ykNmm2EditField.Value/1.15
            fctm = 0.3*fck^(2/3)
            fctk = 0.7*fctm
            fctd = fctk/1.5
            

            if app.ClassediduttilitDropDown.Value == 'CDA'
            gammard = 1.2
            else
            gammard = 1.1
            end

            if app.NodoDropDown.Value == 'Interno'
            alpha = 0.6
            else %nodo d'angolo è considerato come esterno
            alpha = 0.48
            end
            As1 = max(As1sx,As1dx)
            As2 = max(As2sx,As2dx)

            Vc = app.VckNEditField.Value

            Vjbd = (gammard*fyd*(As1+As2)-Vc*1000)/1000

 
            eta = alpha*(1-fck/250)
            nid = Nmax/(min(bcinf,bcsup)*min(hcinf,hcsup)*fcd)
            Vjcrd = eta*fcd*bjc*hjc*sqrt(1-nid/eta)/1000
         

            app.ResistenzaacompressionekNEditField.Value = Vjcrd

            app.kNEditField.Value = Vjbd
                
            if Vjcrd > Vjbd 
            app.Lamp.Color = [0.00,1.00,0.00]   
            else 
app.Lamp.Color = [1.00,0.00,0.00] 
            end

            taum = Vjbd*1000/(bjc*hjc)
            sigmam = Nmin/(min(bcinf,bcsup)*min(hcinf,hcsup))

            sigmajt = sigmam/2-sqrt(taum^2+sigmam^2)
            app.kNEditField_2.Value = abs(sigmajt)
            app.ResistenzaacompressionekNEditField_2.Value = fctd
            if fctd > abs(sigmajt) 
            app.Lamp_2.Color = [0.00,1.00,0.00]   
            else 
app.Lamp_2.Color = [1.00,0.00,0.00] 
            end

            if fctd > abs(sigmajt) 
            app.Label_2.Text = "Non necessaria armatura a trazione"
            else
            app.Label_2.Text = "Necessaria armatura a trazione"
            end

            
Asw1 = ((taum^2/(sigmam + fctd))-fctd)*bjc*hjw/fyd
Asw2 = Vjbd*1000*(1-0.8*sigmam/fcd)/fyd

app.Ashmm2EditField.Value = Asw1
app.Ashmm2EditField_2.Value = Asw2
app.Ashmm2EditField_3.Value = min(Asw1,Asw2)


%%%%%%%%%%%%%%%%% SINGOLA TRAVE ---- FRONTALE SOLO   %%%%%%%%%%%%%%%%%%%%
            elseif app.Button.Value

             bjc = app.bjmmEditField.Value
            hjc = app.hjcmmEditField.Value
            hjw = app.hjwmmEditField.Value
            Nmax = app.N_maxkNEditField.Value*1000
            Nmin = app.N_minkNEditField.Value*1000

            
            bcinf = app.b_cinfmmEditField.Value
            bcsup = app.b_csupmmEditField.Value
            hcinf = app.h_cinfmmEditField.Value
            hcsup = app.h_csupmmEditField.Value
            cinf = app.copriferro_infmmEditField.Value
            csup = app.copriferro_supmmEditField.Value
            btf = app.b_tfmmEditField.Value 
            htf = app.h_tfmmEditField.Value 
            cf = app.copriferro_fmmEditField.Value
            
            As1f = app.A_fs1mm2EditField.Value
            As2f = app.A_fs2mm2EditField.Value


            bjc = min(min(bcinf,bcsup),btf)
            app.bjmmEditField.Value = bjc
            hjc = min(hcinf-2*cinf, hcsup-2*csup)
            app.hjcmmEditField.Value = hjc
            hjw = htf-2*cf
            app.hjwmmEditField.Value = hjw


            classe = app.CalcestruzzoDropDown.Value
            if classe == 'C28/35'
                fck = 0.83*35
            elseif classe == 'C20/25'
                fck = 0.83*25
            elseif classe == 'C25/30'
                fck = 0.83*30
            elseif classe == 'C32/40'
                fck = 0.83*40
            elseif classe == 'C35/45'
                fck = 0.83*45
            elseif classe == 'C40/50'
                fck = 0.83*50
            elseif classe == 'C45/55'
                fck = 0.83*55
            elseif classe == 'C16/20'
                fck = 0.83*20
            else
                fck = app.f_ckNmm2EditField.Value


            end
  
            fcd = 0.85*fck/1.5
            fyd = app.f_ykNmm2EditField.Value/1.15
            fctm = 0.3*fck^(2/3)
            fctk = 0.7*fctm
            fctd = fctk/1.5
            

            if app.ClassediduttilitDropDown.Value == 'CDA'
            gammard = 1.2
            else
            gammard = 1.1
            end

            %c'e sempre un sola trave convergente in questa direzione
            %quindi il nodo è considerato come esterno.
            alpha = 0.48
            
            As1 = As1f
            As2 = As2f
            Vc = app.VckNEditField.Value

            Vjbd = (gammard*fyd*(As1)-Vc*1000)/1000

 
            eta = alpha*(1-fck/250)
            nid = Nmax/(min(bcinf,bcsup)*min(hcinf,hcsup)*fcd)
            Vjcrd = eta*fcd*bjc*hjc*sqrt(1-nid/eta)/1000
         

            app.ResistenzaacompressionekNEditField.Value = Vjcrd

            app.kNEditField.Value = Vjbd
                
            if Vjcrd > Vjbd 
            app.Lamp.Color = [0.00,1.00,0.00]   
            else 
app.Lamp.Color = [1.00,0.00,0.00] 
            end

            taum = Vjbd*1000/(bjc*hjc)
            sigmam = Nmin/(min(bcinf,bcsup)*min(hcinf,hcsup))

            sigmajt = sigmam/2-sqrt(taum^2+sigmam^2)
            app.kNEditField_2.Value = abs(sigmajt)
            app.ResistenzaacompressionekNEditField_2.Value = fctd
            if fctd > abs(sigmajt) 
            app.Lamp_2.Color = [0.00,1.00,0.00]   
            else 
app.Lamp_2.Color = [1.00,0.00,0.00] 
            end

            if fctd > abs(sigmajt) 
            app.Label_2.Text = "Non necessaria armatura a trazione"
            else
            app.Label_2.Text = "Necessaria armatura a trazione"
            end

            
Asw1 = ((taum^2/(sigmam + fctd))-fctd)*bjc*hjw/fyd
Asw2 = Vjbd*1000*(1-0.8*sigmam/fcd)/fyd

app.Ashmm2EditField.Value = Asw1
app.Ashmm2EditField_2.Value = Asw2
app.Ashmm2EditField_3.Value = min(Asw1,Asw2)


            end 

        end

        % Menu selected function: Nodointerno2traviconvergentiMenu
        function Nodointerno2traviconvergentiMenuSelected(app, event)
            %% ESEMPIO INTERNO -------- DUE TRAVI CONVERGENTI %%%%
            app.N_maxkNEditField.Value = 650
            app.N_minkNEditField.Value = 230
            app.VckNEditField.Value = 100
            app.copriferro_supmmEditField.Value = 40
            app.h_csupmmEditField.Value = 700
            app.b_csupmmEditField.Value = 350
            app.copriferro_infmmEditField.Value = 40
            app.h_cinfmmEditField.Value = 700
            app.b_cinfmmEditField.Value = 350
            app.b_tsxmmEditField.Value = 350
            app.h_tsxmmEditField.Value = 550
            app.copriferro_sxmmEditField.Value = 40
            app.A_sxs1mm2EditField.Value = 770
            app.A_sxs2mm2EditField.Value = 770
            app.b_tdxmmEditField.Value = 350
            app.h_tdxmmEditField.Value = 550
            app.copriferro_dxmmEditField.Value = 40
            app.A_dxs1mm2EditField.Value = 770
            app.A_dxs2mm2EditField.Value = 770


        end

        % Value changed function: CalcestruzzoDropDown
        function CalcestruzzoDropDownValueChanged(app, event)
            classe = app.CalcestruzzoDropDown.Value
            if classe == 'C28/35'
                fck = 0.83*35
            elseif classe == 'C20/25'
                fck = 0.83*25
            elseif classe == 'C25/30'
                fck = 0.83*30
            elseif classe == 'C32/40'
                fck = 0.83*40
            elseif classe == 'C35/45'
                fck = 0.83*45
            elseif classe == 'C40/50'
                fck = 0.83*50
            elseif classe == 'C45/55'
                fck = 0.83*55
            elseif classe == 'C16/20'
                fck = 0.83*20
            else
            app.f_ckNmm2EditField.Editable = 'on'
            fck = app.f_ckNmm2EditField.Value 
            end
  
            fcd = 0.85*fck/1.5
            fctm = 0.3*fck^(2/3)
            fctk = 0.7*fctm
            fctd = fctk/1.5

            app.f_cdNmm2EditField.Value = fcd
            app.f_ctmNmm2EditField.Value = fctm
            app.f_ctkNmm2EditField.Value = fctk
            app.f_ctdNmm2EditField.Value = fctd
            app.f_ckNmm2EditField.Value = fck
        end

        % Drop down opening function: CalcestruzzoDropDown
        function CalcestruzzoDropDownOpening(app, event)
            
        end

        % Value changed function: AcciaioDropDown
        function AcciaioDropDownValueChanged(app, event)
            value = app.AcciaioDropDown.Value;
            if value == 'B450C'
            app.f_ykNmm2EditField.Value = 450
            app.f_ykNmm2EditField.Editable = 'off'
            else
           app.f_ykNmm2EditField.Editable = 'on'
            end
        end

        % Button pushed function: CalcolaButton_2
        function CalcolaButton_2Pushed(app, event)
            
            if app.Button_2.Value
            bjc = app.bjmmEditField.Value
            hjc = app.hjcmmEditField.Value
            hjw = app.hjwmmEditField.Value
            Nmax = app.N_maxkNEditField.Value*1000
            Nmin = app.N_minkNEditField.Value*1000

            
            bcinf = app.b_cinfmmEditField.Value
            bcsup = app.b_csupmmEditField.Value
            btdx = app.b_tdxmmEditField.Value
            btsx = app.b_tsxmmEditField.Value
            hcinf = app.h_cinfmmEditField.Value
            hcsup = app.h_csupmmEditField.Value
            cinf = app.copriferro_infmmEditField.Value
            csup = app.copriferro_supmmEditField.Value
            cdx = app.copriferro_dxmmEditField.Value
            csx = app.copriferro_sxmmEditField.Value
            htdx = app.h_tdxmmEditField.Value
            htsx = app.h_tsxmmEditField.Value
            As1dx = app.A_dxs1mm2EditField.Value
            As1sx = app.A_sxs1mm2EditField.Value  
            As2dx = app.A_dxs2mm2EditField.Value
            As2sx = app.A_sxs2mm2EditField.Value


            bjc = min(min(bcinf,bcsup),min(btsx,btdx))
            app.bjmmEditField.Value = bjc
            hjc = min(hcinf-2*cinf, hcsup-2*csup)
            app.hjcmmEditField.Value = hjc
            hjw = min(htdx-2*cdx,htsx-2*csx)
            app.hjwmmEditField.Value = hjw

            elseif app.Button.Value 
                bjc = app.bjmmEditField.Value
            hjc = app.hjcmmEditField.Value
            hjw = app.hjwmmEditField.Value
            Nmax = app.N_maxkNEditField.Value*1000
            Nmin = app.N_minkNEditField.Value*1000

            
            bcinf = app.b_cinfmmEditField.Value
            bcsup = app.b_csupmmEditField.Value
            hcinf = app.h_cinfmmEditField.Value
            hcsup = app.h_csupmmEditField.Value
            cinf = app.copriferro_infmmEditField.Value
            csup = app.copriferro_supmmEditField.Value
            btf = app.b_tfmmEditField.Value 
            htf = app.h_tfmmEditField.Value 
            cf = app.copriferro_fmmEditField.Value
            
            As1f = app.A_fs1mm2EditField.Value
            As2f = app.A_fs2mm2EditField.Value

            bjc = min(min(bcinf,bcsup),btf)
            app.bjmmEditField.Value = bjc
            hjc = min(hcinf-2*cinf, hcsup-2*csup)
            app.hjcmmEditField.Value = hjc
            hjw = htf-2*cf
            app.hjwmmEditField.Value = hjw
            end


        end

        % Value changed function: f_ckNmm2EditField
        function f_ckNmm2EditFieldValueChanged(app, event)
            fck = app.f_ckNmm2EditField.Value;
            fcd = 0.85*fck/1.5
            fctm = 0.3*fck^(2/3)
            fctk = 0.7*fctm
            fctd = fctk/1.5

            app.f_cdNmm2EditField.Value = fcd
            app.f_ctmNmm2EditField.Value = fctm
            app.f_ctkNmm2EditField.Value = fctk
            app.f_ctdNmm2EditField.Value = fctd
            app.f_ckNmm2EditField.Value = fck
        end

        % Value changed function: f_ykNmm2EditField
        function f_ykNmm2EditFieldValueChanged(app, event)
            value = app.f_ykNmm2EditField.Value;
            app.f_ydNmm2EditField.Value = value/1.15
        end

        % Selection changed function: TraviconvergentiButtonGroup
        function TraviconvergentiButtonGroupSelectionChanged(app, event)
            if app.Button.Value
            app.Image.Visible = 'off'
            app.TravesinistraPanel.Visible = 'off'
            app.TravedestraPanel.Visible = 'off'
            app.Image7.Visible = 'on'
            app.TravefrontalePanel.Visible = 'on'
            elseif app.Button_2.Value 
                app.Image.Visible = 'on'
            app.TravesinistraPanel.Visible = 'on'
            app.TravedestraPanel.Visible = 'on'
            app.Image7.Visible = 'off'
            app.TravefrontalePanel.Visible = 'off'
            end 
        
        end

        % Value changed function: NodoDropDown
        function NodoDropDownValueChanged(app, event)
%             value = app.NodoDropDown.Value;
%             if value =='Angolo '
%                 app.Button.Value = 1
%             elseif value == 'Interno'
% app.Button_2.Value = 1
%             end 

        end

        % Drop down opening function: NodoDropDown
        function NodoDropDownOpening(app, event)
            
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Menu selected function: Nodoesterno1traveconvergenteMenu
        function Nodoesterno1traveconvergenteMenuSelected(app, event)
            %%%%%%% ESEMPIO NODO ESTERNO ------- UNICA TRAVE FRONTALE
            app.N_maxkNEditField.Value = 650
            app.N_minkNEditField.Value = 230
            app.VckNEditField.Value = 100
            app.copriferro_supmmEditField.Value = 40
            app.h_csupmmEditField.Value = 350
            app.b_csupmmEditField.Value = 700
            app.copriferro_infmmEditField.Value = 40
            app.h_cinfmmEditField.Value = 350
            app.b_cinfmmEditField.Value = 700
            app.b_tfmmEditField.Value = 525
            app.h_tfmmEditField.Value = 550
            app.copriferro_sxmmEditField.Value = 40
            app.A_fs1mm2EditField.Value = 770
            app.A_fs2mm2EditField.Value = 770
            app.copriferro_fmmEditField.Value = 40
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MaterialiesollecitazioniTab = uitab(app.TabGroup);
            app.MaterialiesollecitazioniTab.Title = 'Materiali e sollecitazioni';
            app.MaterialiPanel = uipanel(app.MaterialiesollecitazioniTab);
            app.MaterialiPanel.Title = 'Materiali';
            app.CalcestruzzoDropDownLabel = uilabel(app.MaterialiPanel);
            app.CalcestruzzoDropDownLabel.Interpreter = 'latex';
            app.CalcestruzzoDropDownLabel.Text = 'Calcestruzzo:';
            app.CalcestruzzoDropDown = uidropdown(app.MaterialiPanel);
            app.CalcestruzzoDropDown.Items = {'C16/20', 'C20/25', 'C25/30', 'C28/35', 'C32/40', 'C35/45', 'C40/50', 'C45/55', 'Altro '};
            app.CalcestruzzoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CalcestruzzoDropDownOpening, true);
            app.CalcestruzzoDropDown.ValueChangedFcn = createCallbackFcn(app, @CalcestruzzoDropDownValueChanged, true);
            app.CalcestruzzoDropDown.Value = 'C25/30';
            app.AcciaioDropDownLabel = uilabel(app.MaterialiPanel);
            app.AcciaioDropDownLabel.Interpreter = 'latex';
            app.AcciaioDropDownLabel.Text = 'Acciaio: ';
            app.AcciaioDropDown = uidropdown(app.MaterialiPanel);
            app.AcciaioDropDown.Items = {'B450C', 'Altro'};
            app.AcciaioDropDown.ValueChangedFcn = createCallbackFcn(app, @AcciaioDropDownValueChanged, true);
            app.AcciaioDropDown.Value = 'B450C';
            app.f_ykNmm2Label = uilabel(app.MaterialiPanel);
            app.f_ykNmm2Label.Interpreter = 'latex';
            app.f_ykNmm2Label.Text = '$f_{yk} [N/mm^2]$';
            app.f_ykNmm2EditField = uieditfield(app.MaterialiPanel, 'numeric');
            app.f_ykNmm2EditField.ValueChangedFcn = createCallbackFcn(app, @f_ykNmm2EditFieldValueChanged, true);
            app.f_ykNmm2EditField.Editable = 'off';
            app.f_ykNmm2EditField.Value = 450;
            app.f_cdNmm2EditFieldLabel = uilabel(app.MaterialiPanel);
            app.f_cdNmm2EditFieldLabel.Interpreter = 'latex';
            app.f_cdNmm2EditFieldLabel.Text = '$f_{cd} [N/mm^2]$';
            app.f_cdNmm2EditField = uieditfield(app.MaterialiPanel, 'numeric');
            app.f_cdNmm2EditField.Editable = 'off';
            app.f_cdNmm2EditField.Value = 14.11;
            app.f_ctmNmm2EditFieldLabel = uilabel(app.MaterialiPanel);
            app.f_ctmNmm2EditFieldLabel.Interpreter = 'latex';
            app.f_ctmNmm2EditFieldLabel.Text = '$f_{ctm} [N/mm^2]$';
            app.f_ctmNmm2EditField = uieditfield(app.MaterialiPanel, 'numeric');
            app.f_ctmNmm2EditField.Editable = 'off';
            app.f_ctmNmm2EditField.Value = 2.558;
            app.f_ctkNmm2EditFieldLabel = uilabel(app.MaterialiPanel);
            app.f_ctkNmm2EditFieldLabel.Interpreter = 'latex';
            app.f_ctkNmm2EditFieldLabel.Text = '$f_{ctk} [N/mm^2]$';
            app.f_ctkNmm2EditField = uieditfield(app.MaterialiPanel, 'numeric');
            app.f_ctkNmm2EditField.Editable = 'off';
            app.f_ctkNmm2EditField.Value = 1.791;
            app.f_ctdNmm2EditFieldLabel = uilabel(app.MaterialiPanel);
            app.f_ctdNmm2EditFieldLabel.Interpreter = 'latex';
            app.f_ctdNmm2EditFieldLabel.Text = '$f_{ctd} [N/mm^2]$';
            app.f_ctdNmm2EditField = uieditfield(app.MaterialiPanel, 'numeric');
            app.f_ctdNmm2EditField.Editable = 'off';
            app.f_ctdNmm2EditField.Value = 1.194;
            app.f_ckNmm2EditFieldLabel = uilabel(app.MaterialiPanel);
            app.f_ckNmm2EditFieldLabel.Interpreter = 'latex';
            app.f_ckNmm2EditFieldLabel.Text = '$f_{ck} [N/mm^2]$';
            app.f_ckNmm2EditField = uieditfield(app.MaterialiPanel, 'numeric');
            app.f_ckNmm2EditField.ValueChangedFcn = createCallbackFcn(app, @f_ckNmm2EditFieldValueChanged, true);
            app.f_ckNmm2EditField.Editable = 'off';
            app.f_ckNmm2EditField.Value = 24.9;
            app.f_ydNmm2EditFieldLabel = uilabel(app.MaterialiPanel);
            app.f_ydNmm2EditFieldLabel.Interpreter = 'latex';
            app.f_ydNmm2EditFieldLabel.Text = '$f_{yd} [N/mm^2]$';
            app.f_ydNmm2EditField = uieditfield(app.MaterialiPanel, 'numeric');
            app.f_ydNmm2EditField.Editable = 'off';
            app.f_ydNmm2EditField.Value = 391.3;
            app.SollecitazioniPanel = uipanel(app.MaterialiesollecitazioniTab);
            app.SollecitazioniPanel.Title = 'Sollecitazioni';
            app.N_minkNEditFieldLabel = uilabel(app.SollecitazioniPanel);
            app.N_minkNEditFieldLabel.Interpreter = 'latex';
            app.N_minkNEditFieldLabel.Text = '$N_{min} [kN]$';
            app.N_minkNEditField = uieditfield(app.SollecitazioniPanel, 'numeric');
            app.NmaxkNLabel = uilabel(app.SollecitazioniPanel);
            app.NmaxkNLabel.Interpreter = 'latex';
            app.NmaxkNLabel.Text = '$N_{max} [kN]$';
            app.N_maxkNEditField = uieditfield(app.SollecitazioniPanel, 'numeric');
            app.VckNLabel = uilabel(app.SollecitazioniPanel);
            app.VckNLabel.Interpreter = 'latex';
            app.VckNLabel.Text = '$Vc [kN]$';
            app.VckNEditField = uieditfield(app.SollecitazioniPanel, 'numeric');
            app.Image3 = uiimage(app.SollecitazioniPanel);
            app.Image3.ImageSource = 'Ned.jpg';
            app.Label_9 = uilabel(app.MaterialiesollecitazioniTab);
            app.Label_9.Interpreter = 'latex';
            app.Label_9.Text = {'Per eseguire le verifiche dei nodi è necessario definire le caratteristiche meccaniche dei materiali. Selezionare la classe di calcestruzzo e '; 'la tipologia di acciaio impiegate nei relativi menù. Se dovessero essere diverse rispetto quelle presenti, selzionare "Altro" e specificare '; 'manualmente la resistenza caratteristica a compressione del calcelstruzzo $f_{ck}$ e la resistenza caratterisitca allo '; 'snervamento dell''acciaio $f_{yk}$, a partire dai valori inseriti verranno calcolati gli altri.'};
            app.Label_10 = uilabel(app.MaterialiesollecitazioniTab);
            app.Label_10.Interpreter = 'latex';
            app.Label_10.Text = {'Dopo aver condotto le analisi sismiche, considerare il valore massimo e minimo dello sforzo normale agente in sommità '; 'del pannello nodale e il minimo valore del taglio $V_c$.'};
            app.RealizzatodaGianPioScottodiMaseLabel_5 = uilabel(app.MaterialiesollecitazioniTab);
            app.RealizzatodaGianPioScottodiMaseLabel_5.Text = 'Realizzato da Gian Pio Scotto di Mase ';
            app.PosizioneTab = uitab(app.TabGroup);
            app.PosizioneTab.Title = 'Posizione';
            app.DefinirelaposizionedelnodoelaclassediduttilitdelledificioLabel = uilabel(app.PosizioneTab);
            app.DefinirelaposizionedelnodoelaclassediduttilitdelledificioLabel.Interpreter = 'latex';
            app.DefinirelaposizionedelnodoelaclassediduttilitdelledificioLabel.Text = {'Specificare la classe di duttilità dell''edificio, la posizione del nodo da verificare e il numero di travi convergenti contenute '; 'nello stesso piano. Nel caso di nodi interni il numero  di travi convergenti è sempre 2, indipendentemente dal piano, '; 'mentre nel caso di nodi d''angolo è sempre 1. '; 'Nel caso di nodo esterno dipende dal piano in cui si esegue la verifica, selezionare di conseguenza.'};
            app.NodoDropDownLabel = uilabel(app.PosizioneTab);
            app.NodoDropDownLabel.Interpreter = 'latex';
            app.NodoDropDownLabel.Text = 'Nodo';
            app.NodoDropDown = uidropdown(app.PosizioneTab);
            app.NodoDropDown.Items = {'Interno', 'Esterno', 'Angolo '};
            app.NodoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @NodoDropDownOpening, true);
            app.NodoDropDown.ValueChangedFcn = createCallbackFcn(app, @NodoDropDownValueChanged, true);
            app.NodoDropDown.Value = 'Interno';
            app.ClassediduttilitDropDownLabel = uilabel(app.PosizioneTab);
            app.ClassediduttilitDropDownLabel.Interpreter = 'latex';
            app.ClassediduttilitDropDownLabel.Text = 'Classe di duttilità';
            app.ClassediduttilitDropDown = uidropdown(app.PosizioneTab);
            app.ClassediduttilitDropDown.Items = {'CDA', 'CDB'};
            app.ClassediduttilitDropDown.Value = 'CDA';
            app.EsempidinodiPanel = uipanel(app.PosizioneTab);
            app.EsempidinodiPanel.Title = 'Esempi di nodi';
            app.Image2 = uiimage(app.EsempidinodiPanel);
            app.Image2.ImageSource = 'esempi nodi.jpg';
            app.RealizzatodaGianPioScottodiMaseLabel_3 = uilabel(app.PosizioneTab);
            app.RealizzatodaGianPioScottodiMaseLabel_3.Text = 'Realizzato da Gian Pio Scotto di Mase ';
            app.TraviconvergentiButtonGroup = uibuttongroup(app.PosizioneTab);
            app.TraviconvergentiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TraviconvergentiButtonGroupSelectionChanged, true);
            app.TraviconvergentiButtonGroup.Title = 'Travi convergenti';
            app.Button = uiradiobutton(app.TraviconvergentiButtonGroup);
            app.Button.Text = '1';
            app.Button_2 = uiradiobutton(app.TraviconvergentiButtonGroup);
            app.Button_2.Text = '2';
            app.Button_2.Value = true;
            app.Label_11 = uilabel(app.TraviconvergentiButtonGroup);
            app.Label_11.Text = {'Numero di travi che convergono '; 'nel nodo, considerando quelle nel '; 'piano in cui si esegue la verifica. '};
            app.Image8 = uiimage(app.TraviconvergentiButtonGroup);
            app.Image8.ImageSource = 'esempio travi.jpg';
            app.EsempioLabel = uilabel(app.TraviconvergentiButtonGroup);
            app.EsempioLabel.Text = 'Esempio';
            app.Pianoyz2Pianoxz1Label = uilabel(app.TraviconvergentiButtonGroup);
            app.Pianoyz2Pianoxz1Label.Text = {'Piano y-z: 2'; 'Piano x-z: 1'};
            app.GeometriaearmatureTab = uitab(app.TabGroup);
            app.GeometriaearmatureTab.Title = 'Geometria e armature';
            app.Image = uiimage(app.GeometriaearmatureTab);
            app.Image.ImageSource = 'indicazioni.jpg';
            app.TravesinistraPanel = uipanel(app.GeometriaearmatureTab);
            app.TravesinistraPanel.Title = 'Trave sinistra';
            app.b_tsxmmEditFieldLabel = uilabel(app.TravesinistraPanel);
            app.b_tsxmmEditFieldLabel.Interpreter = 'latex';
            app.b_tsxmmEditFieldLabel.Text = '$b_{t,sx} [mm]$';
            app.b_tsxmmEditField = uieditfield(app.TravesinistraPanel, 'numeric');
            app.h_tsxmmEditFieldLabel = uilabel(app.TravesinistraPanel);
            app.h_tsxmmEditFieldLabel.Interpreter = 'latex';
            app.h_tsxmmEditFieldLabel.Text = '$h_{t,sx} [mm]$';
            app.h_tsxmmEditField = uieditfield(app.TravesinistraPanel, 'numeric');
            app.As1mm2Label = uilabel(app.TravesinistraPanel);
            app.As1mm2Label.Interpreter = 'latex';
            app.As1mm2Label.Text = '$A_{sx,s1} [mm^2]$';
            app.A_sxs1mm2EditField = uieditfield(app.TravesinistraPanel, 'numeric');
            app.As2mm2Label = uilabel(app.TravesinistraPanel);
            app.As2mm2Label.Interpreter = 'latex';
            app.As2mm2Label.Text = '$A_{sx,s2} [mm^2]$';
            app.A_sxs2mm2EditField = uieditfield(app.TravesinistraPanel, 'numeric');
            app.copriferro_sxmmEditFieldLabel = uilabel(app.TravesinistraPanel);
            app.copriferro_sxmmEditFieldLabel.Interpreter = 'latex';
            app.copriferro_sxmmEditFieldLabel.Text = '$copriferro_{sx}[mm]$';
            app.copriferro_sxmmEditField = uieditfield(app.TravesinistraPanel, 'numeric');
            app.TravedestraPanel = uipanel(app.GeometriaearmatureTab);
            app.TravedestraPanel.Title = 'Trave destra';
            app.b_tdxmmEditFieldLabel = uilabel(app.TravedestraPanel);
            app.b_tdxmmEditFieldLabel.Interpreter = 'latex';
            app.b_tdxmmEditFieldLabel.Text = '$b_{t,dx} [mm]$';
            app.b_tdxmmEditField = uieditfield(app.TravedestraPanel, 'numeric');
            app.h_tdxmmEditFieldLabel = uilabel(app.TravedestraPanel);
            app.h_tdxmmEditFieldLabel.Interpreter = 'latex';
            app.h_tdxmmEditFieldLabel.Text = '$h_{t,dx} [mm]$';
            app.h_tdxmmEditField = uieditfield(app.TravedestraPanel, 'numeric');
            app.A_dxs1mm2EditFieldLabel = uilabel(app.TravedestraPanel);
            app.A_dxs1mm2EditFieldLabel.Interpreter = 'latex';
            app.A_dxs1mm2EditFieldLabel.Text = '$A_{dx,s1} [mm^2]$';
            app.A_dxs1mm2EditField = uieditfield(app.TravedestraPanel, 'numeric');
            app.A_dxs2mm2EditFieldLabel = uilabel(app.TravedestraPanel);
            app.A_dxs2mm2EditFieldLabel.Interpreter = 'latex';
            app.A_dxs2mm2EditFieldLabel.Text = '$A_{dx,s2} [mm^2]$';
            app.A_dxs2mm2EditField = uieditfield(app.TravedestraPanel, 'numeric');
            app.copriferro_dxmmEditFieldLabel = uilabel(app.TravedestraPanel);
            app.copriferro_dxmmEditFieldLabel.Interpreter = 'latex';
            app.copriferro_dxmmEditFieldLabel.Text = '$copriferro_{dx}[mm]$';
            app.copriferro_dxmmEditField = uieditfield(app.TravedestraPanel, 'numeric');
            app.PilastrosuperiorePanel = uipanel(app.GeometriaearmatureTab);
            app.PilastrosuperiorePanel.Title = 'Pilastro superiore';
            app.b_csupmmEditFieldLabel = uilabel(app.PilastrosuperiorePanel);
            app.b_csupmmEditFieldLabel.Interpreter = 'latex';
            app.b_csupmmEditFieldLabel.Text = '$b_{c,sup} [mm]$';
            app.b_csupmmEditField = uieditfield(app.PilastrosuperiorePanel, 'numeric');
            app.h_csupmmEditFieldLabel = uilabel(app.PilastrosuperiorePanel);
            app.h_csupmmEditFieldLabel.Interpreter = 'latex';
            app.h_csupmmEditFieldLabel.Text = '$h_{c,sup} [mm]$';
            app.h_csupmmEditField = uieditfield(app.PilastrosuperiorePanel, 'numeric');
            app.copriferro_supmmEditFieldLabel = uilabel(app.PilastrosuperiorePanel);
            app.copriferro_supmmEditFieldLabel.Interpreter = 'latex';
            app.copriferro_supmmEditFieldLabel.Text = '$copriferro_{sup}[mm]$';
            app.copriferro_supmmEditField = uieditfield(app.PilastrosuperiorePanel, 'numeric');
            app.Label_6 = uilabel(app.GeometriaearmatureTab);
            app.Label_6.Interpreter = 'latex';
            app.Label_6.Text = {'Definire le dimensioni degli elementi strutturali che convergono nel '; 'nodo, a partire dalle quali si calcolano le dimensioni del pannello nodale: '};
            app.RealizzatodaGianPioScottodiMaseLabel_2 = uilabel(app.GeometriaearmatureTab);
            app.RealizzatodaGianPioScottodiMaseLabel_2.Text = 'Realizzato da Gian Pio Scotto di Mase ';
            app.Image7 = uiimage(app.GeometriaearmatureTab);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = 'info nodo.jpg';
            app.DimensionipannelloPanel = uipanel(app.GeometriaearmatureTab);
            app.DimensionipannelloPanel.Title = 'Dimensioni pannello';
            app.bjmmLabel = uilabel(app.DimensionipannelloPanel);
            app.bjmmLabel.Interpreter = 'latex';
            app.bjmmLabel.Text = '$bj [mm]$';
            app.bjmmEditField = uieditfield(app.DimensionipannelloPanel, 'numeric');
            app.bjmmEditField.Editable = 'off';
            app.hcmmLabel = uilabel(app.DimensionipannelloPanel);
            app.hcmmLabel.Interpreter = 'latex';
            app.hcmmLabel.Text = '$hjc [mm]$';
            app.hjcmmEditField = uieditfield(app.DimensionipannelloPanel, 'numeric');
            app.hjcmmEditField.Editable = 'off';
            app.hwmmLabel = uilabel(app.DimensionipannelloPanel);
            app.hwmmLabel.Interpreter = 'latex';
            app.hwmmLabel.Text = '$hjw [mm]$';
            app.hjwmmEditField = uieditfield(app.DimensionipannelloPanel, 'numeric');
            app.hjwmmEditField.Editable = 'off';
            app.Image6 = uiimage(app.DimensionipannelloPanel);
            app.Image6.ImageSource = 'pannello_nodale.jpg';
            app.CalcolaButton_2 = uibutton(app.DimensionipannelloPanel, 'push');
            app.CalcolaButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcolaButton_2Pushed, true);
            app.CalcolaButton_2.Text = 'Calcola';
            app.PilastroinferiorePanel = uipanel(app.GeometriaearmatureTab);
            app.PilastroinferiorePanel.Title = 'Pilastro inferiore';
            app.b_cinfmmEditFieldLabel = uilabel(app.PilastroinferiorePanel);
            app.b_cinfmmEditFieldLabel.Interpreter = 'latex';
            app.b_cinfmmEditFieldLabel.Text = '$b_{c,inf} [mm]$';
            app.b_cinfmmEditField = uieditfield(app.PilastroinferiorePanel, 'numeric');
            app.h_cinfmmEditFieldLabel = uilabel(app.PilastroinferiorePanel);
            app.h_cinfmmEditFieldLabel.Interpreter = 'latex';
            app.h_cinfmmEditFieldLabel.Text = '$h_{c,inf} [mm]$';
            app.h_cinfmmEditField = uieditfield(app.PilastroinferiorePanel, 'numeric');
            app.copriferro_infmmEditFieldLabel = uilabel(app.PilastroinferiorePanel);
            app.copriferro_infmmEditFieldLabel.Interpreter = 'latex';
            app.copriferro_infmmEditFieldLabel.Text = '$copriferro_{inf}[mm]$';
            app.copriferro_infmmEditField = uieditfield(app.PilastroinferiorePanel, 'numeric');
            app.TravefrontalePanel = uipanel(app.GeometriaearmatureTab);
            app.TravefrontalePanel.Title = 'Trave frontale';
            app.TravefrontalePanel.Visible = 'off';
            app.b_tfmmEditFieldLabel = uilabel(app.TravefrontalePanel);
            app.b_tfmmEditFieldLabel.Interpreter = 'latex';
            app.b_tfmmEditFieldLabel.Text = '$b_{t,f} [mm]$';
            app.b_tfmmEditField = uieditfield(app.TravefrontalePanel, 'numeric');
            app.h_tfmmLabel = uilabel(app.TravefrontalePanel);
            app.h_tfmmLabel.Interpreter = 'latex';
            app.h_tfmmLabel.Text = {'$h_{t,f} [mm]$'; ''};
            app.h_tfmmEditField = uieditfield(app.TravefrontalePanel, 'numeric');
            app.A_fs1mm2EditFieldLabel = uilabel(app.TravefrontalePanel);
            app.A_fs1mm2EditFieldLabel.Interpreter = 'latex';
            app.A_fs1mm2EditFieldLabel.Text = '$A_{f,s1} [mm^2]$';
            app.A_fs1mm2EditField = uieditfield(app.TravefrontalePanel, 'numeric');
            app.A_fs2mm2EditFieldLabel = uilabel(app.TravefrontalePanel);
            app.A_fs2mm2EditFieldLabel.Interpreter = 'latex';
            app.A_fs2mm2EditFieldLabel.Text = '$A_{f,s2} [mm^2]$';
            app.A_fs2mm2EditField = uieditfield(app.TravefrontalePanel, 'numeric');
            app.copriferro_fmmEditFieldLabel = uilabel(app.TravefrontalePanel);
            app.copriferro_fmmEditFieldLabel.Interpreter = 'latex';
            app.copriferro_fmmEditFieldLabel.Text = '$copriferro_{f}[mm]$';
            app.copriferro_fmmEditField = uieditfield(app.TravefrontalePanel, 'numeric');
            app.VerificaTab = uitab(app.TabGroup);
            app.VerificaTab.Title = 'Verifica';
            app.VerificaacompressionePanel = uipanel(app.VerificaTab);
            app.VerificaacompressionePanel.Title = 'Verifica a compressione ';
            app.ResistenzaacompressionekNEditField = uieditfield(app.VerificaacompressionePanel, 'numeric');
            app.ResistenzaacompressionekNEditField.Editable = 'off';
            app.kNEditField = uieditfield(app.VerificaacompressionePanel, 'numeric');
            app.kNEditField.Editable = 'off';
            app.Lamp = uilamp(app.VerificaacompressionePanel);
            app.VjbdkNLabel = uilabel(app.VerificaacompressionePanel);
            app.VjbdkNLabel.Interpreter = 'latex';
            app.VjbdkNLabel.Text = '$Vjbd [kN]$';
            app.ResistenteLabel = uilabel(app.VerificaacompressionePanel);
            app.ResistenteLabel.Interpreter = 'latex';
            app.ResistenteLabel.Text = '$Resistente$';
            app.CalcoloarmaturaPanel = uipanel(app.VerificaTab);
            app.CalcoloarmaturaPanel.Title = 'Calcolo armatura ';
            app.Aswmm2Label = uilabel(app.CalcoloarmaturaPanel);
            app.Aswmm2Label.Interpreter = 'latex';
            app.Aswmm2Label.Text = '$Ash  [mm^2]$';
            app.Ashmm2EditField = uieditfield(app.CalcoloarmaturaPanel, 'numeric');
            app.Ashmm2EditField.Editable = 'off';
            app.Aswmm2Label_2 = uilabel(app.CalcoloarmaturaPanel);
            app.Aswmm2Label_2.Interpreter = 'latex';
            app.Aswmm2Label_2.Text = '$Ash [mm^2] $';
            app.Ashmm2EditField_2 = uieditfield(app.CalcoloarmaturaPanel, 'numeric');
            app.Ashmm2EditField_2.Editable = 'off';
            app.Prefessurazione1Label = uilabel(app.CalcoloarmaturaPanel);
            app.Prefessurazione1Label.Interpreter = 'latex';
            app.Prefessurazione1Label.Text = 'Pre-fessurazione [1]';
            app.Postfessurazione2Label = uilabel(app.CalcoloarmaturaPanel);
            app.Postfessurazione2Label.Interpreter = 'latex';
            app.Postfessurazione2Label.Text = 'Post-fessurazione [2]';
            app.ArmaturaminimadagarantireLabel = uilabel(app.CalcoloarmaturaPanel);
            app.ArmaturaminimadagarantireLabel.Interpreter = 'latex';
            app.ArmaturaminimadagarantireLabel.Text = 'Armatura minima da garantire';
            app.Aswmm2Label_3 = uilabel(app.CalcoloarmaturaPanel);
            app.Aswmm2Label_3.Interpreter = 'latex';
            app.Aswmm2Label_3.Text = '$Ash [mm^2]$';
            app.Ashmm2EditField_3 = uieditfield(app.CalcoloarmaturaPanel, 'numeric');
            app.Ashmm2EditField_3.Editable = 'off';
            app.CalcolaButton = uibutton(app.VerificaTab, 'push');
            app.CalcolaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcolaButtonPushed, true);
            app.CalcolaButton.Text = 'Calcola ';
            app.VerificaatrazionePanel = uipanel(app.VerificaTab);
            app.VerificaatrazionePanel.Title = 'Verifica a trazione ';
            app.ResistenzaacompressionekNEditField_2 = uieditfield(app.VerificaatrazionePanel, 'numeric');
            app.ResistenzaacompressionekNEditField_2.Editable = 'off';
            app.kNEditField_2 = uieditfield(app.VerificaatrazionePanel, 'numeric');
            app.kNEditField_2.Editable = 'off';
            app.Lamp_2 = uilamp(app.VerificaatrazionePanel);
            app.sigma_jtfracNmm2Label = uilabel(app.VerificaatrazionePanel);
            app.sigma_jtfracNmm2Label.Interpreter = 'latex';
            app.sigma_jtfracNmm2Label.Text = '$\sigma_{jt}[\frac{N}{mm^2}]$';
            app.ResistenteLabel_2 = uilabel(app.VerificaatrazionePanel);
            app.ResistenteLabel_2.Interpreter = 'latex';
            app.ResistenteLabel_2.Text = '$Resistente$';
            app.Label_2 = uilabel(app.VerificaatrazionePanel);
            app.Label_2.Text = '    ';
            app.fracA_shcdotf_ywdbjcdoth_jwgeqfracf_ctdnu_dcdotf_cdf_ctdLabel = uilabel(app.VerificaTab);
            app.fracA_shcdotf_ywdbjcdoth_jwgeqfracf_ctdnu_dcdotf_cdf_ctdLabel.Interpreter = 'latex';
            app.fracA_shcdotf_ywdbjcdoth_jwgeqfracf_ctdnu_dcdotf_cdf_ctdLabel.Text = '1) $\frac{A_{sh} \cdot f_{ywd}}{bj \cdot h_{jw}} \geq \frac{[\frac{V_{jbd}}{b_j \cdot h_{jc}}]^2}{f_{ctd} + \nu_d \cdot f_{cd}} - f_{ctd}$';
            app.Label_4 = uilabel(app.VerificaTab);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '2) $A_{sh} \cdot f_{ywd} \geq \gamma_{Rd} \cdot (A_{S1} + A_{S2}) \cdot f_{yd} \cdot (1-0.8 \cdot \nu_d)$';
            app.VerificaatrazioneconarmatureLabel = uilabel(app.VerificaTab);
            app.VerificaatrazioneconarmatureLabel.Interpreter = 'latex';
            app.VerificaatrazioneconarmatureLabel.Text = 'Verifica a trazione (con armature)';
            app.Label_5 = uilabel(app.VerificaTab);
            app.Label_5.Interpreter = 'latex';
            app.Label_5.Text = {'Per il calcolo dell''armatura necessaria si considera un approccio tensionale (pre-fessurazione) e un meccanismo post-fessurazione proposto '; 'in Eurocodice 8, di cui si riportano le formule:'};
            app.Label_8 = uilabel(app.VerificaTab);
            app.Label_8.Interpreter = 'latex';
            app.Label_8.Text = {'Le verifiche tensionali sono condotte in assenza di specifica armatura trasversale, a partire dalle tensioni '; 'principali di compressione e di trazione. Per la verifica a compressione si considera la formula: '; ''; '$V_{jbd} \le b_j \cdot h_{wj} \cdot \eta \cdot f_{cd} \cdot \sqrt{1- \frac{\nu_d}{\eta}$'; ''; ''; 'Mentre per la verifica a trazione si considera la formula: '; ''; '$ \sigma_{jt} \le f_{ctd}$ '};
            app.RealizzatodaGianPioScottodiMaseLabel_4 = uilabel(app.VerificaTab);
            app.RealizzatodaGianPioScottodiMaseLabel_4.Text = 'Realizzato da Gian Pio Scotto di Mase ';
            app.VERIFICANODIECALCOLOARMATURELabel = uilabel(app.UIFigure);
            app.VERIFICANODIECALCOLOARMATURELabel.Text = 'VERIFICA NODI E CALCOLO ARMATURE ';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.Nodointerno2traviconvergentiMenu = uimenu(app.ContextMenu);
            app.Nodointerno2traviconvergentiMenu.MenuSelectedFcn = createCallbackFcn(app, @Nodointerno2traviconvergentiMenuSelected, true);
            app.Nodointerno2traviconvergentiMenu.Text = 'Nodo interno - 2 travi convergenti';
            app.Nodoesterno1traveconvergenteMenu = uimenu(app.ContextMenu);
            app.Nodoesterno1traveconvergenteMenu.MenuSelectedFcn = createCallbackFcn(app, @Nodoesterno1traveconvergenteMenuSelected, true);
            app.Nodoesterno1traveconvergenteMenu.Text = 'Nodo esterno - 1 trave convergente';
            % Assign app.ContextMenu
            app.MaterialiesollecitazioniTab.ContextMenu = app.ContextMenu;
            app.MaterialiPanel.ContextMenu = app.ContextMenu;
            app.CalcestruzzoDropDownLabel.ContextMenu = app.ContextMenu;
            app.CalcestruzzoDropDown.ContextMenu = app.ContextMenu;
            app.SollecitazioniPanel.ContextMenu = app.ContextMenu;
            app.Image3.ContextMenu = app.ContextMenu;
            app.PosizioneTab.ContextMenu = app.ContextMenu;
            app.NodoDropDown.ContextMenu = app.ContextMenu;
            app.ClassediduttilitDropDown.ContextMenu = app.ContextMenu;
            app.EsempidinodiPanel.ContextMenu = app.ContextMenu;
            app.Image2.ContextMenu = app.ContextMenu;
            app.TraviconvergentiButtonGroup.ContextMenu = app.ContextMenu;
            app.Button.ContextMenu = app.ContextMenu;
            app.Button_2.ContextMenu = app.ContextMenu;
            app.Label_11.ContextMenu = app.ContextMenu;
            app.Image8.ContextMenu = app.ContextMenu;
            app.GeometriaearmatureTab.ContextMenu = app.ContextMenu;
            app.Image.ContextMenu = app.ContextMenu;
            app.TravesinistraPanel.ContextMenu = app.ContextMenu;
            app.TravedestraPanel.ContextMenu = app.ContextMenu;
            app.PilastrosuperiorePanel.ContextMenu = app.ContextMenu;
            app.DimensionipannelloPanel.ContextMenu = app.ContextMenu;
            app.hjcmmEditField.ContextMenu = app.ContextMenu;
            app.PilastroinferiorePanel.ContextMenu = app.ContextMenu;
            app.TravefrontalePanel.ContextMenu = app.ContextMenu;
            app.VerificaTab.ContextMenu = app.ContextMenu;
            app.VerificaacompressionePanel.ContextMenu = app.ContextMenu;
            app.ResistenzaacompressionekNEditField.ContextMenu = app.ContextMenu;
            app.kNEditField.ContextMenu = app.ContextMenu;
            app.Lamp.ContextMenu = app.ContextMenu;
            app.CalcoloarmaturaPanel.ContextMenu = app.ContextMenu;
            app.Ashmm2EditField.ContextMenu = app.ContextMenu;
            app.Aswmm2Label_2.ContextMenu = app.ContextMenu;
            app.Ashmm2EditField_2.ContextMenu = app.ContextMenu;
            app.Prefessurazione1Label.ContextMenu = app.ContextMenu;
            app.Postfessurazione2Label.ContextMenu = app.ContextMenu;
            app.ArmaturaminimadagarantireLabel.ContextMenu = app.ContextMenu;
            app.Aswmm2Label_3.ContextMenu = app.ContextMenu;
            app.Ashmm2EditField_3.ContextMenu = app.ContextMenu;
            app.CalcolaButton.ContextMenu = app.ContextMenu;
            app.VerificaatrazionePanel.ContextMenu = app.ContextMenu;
            app.ResistenzaacompressionekNEditField_2.ContextMenu = app.ContextMenu;
            app.Label_2.ContextMenu = app.ContextMenu;
            app.fracA_shcdotf_ywdbjcdoth_jwgeqfracf_ctdnu_dcdotf_cdf_ctdLabel.ContextMenu = app.ContextMenu;
            app.Label_4.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = verifica_nodi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end